classdef fly_on_the_ball_recording_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RecordButton                 matlab.ui.control.Button
        StimulusPanel                matlab.ui.container.Panel
        StimulusLamp                 matlab.ui.control.Lamp
        PreloadButton                matlab.ui.control.Button
        IntensityEditField           matlab.ui.control.EditField
        IntensityEditFieldLabel      matlab.ui.control.Label
        OnsetEditField               matlab.ui.control.EditField
        OnsetEditFieldLabel          matlab.ui.control.Label
        DurationEditField            matlab.ui.control.EditField
        DurationEditFieldLabel       matlab.ui.control.Label
        ViewPanel                    matlab.ui.container.Panel
        MalealignmentCheckBox        matlab.ui.control.CheckBox
        PostviewcamerasCheckBox      matlab.ui.control.CheckBox
        FemalealignmentCheckBox      matlab.ui.control.CheckBox
        PreviewcamerasCheckBox       matlab.ui.control.CheckBox
        FilePanel                    matlab.ui.container.Panel
        TrialdurationEditField       matlab.ui.control.EditField
        TrialdurationEditFieldLabel  matlab.ui.control.Label
        FolderEditField              matlab.ui.control.EditField
        FolderEditFieldLabel         matlab.ui.control.Label
        DirEditField                 matlab.ui.control.EditField
        DirEditFieldLabel            matlab.ui.control.Label
        TrialnumberEditField         matlab.ui.control.EditField
        TrialnumberEditFieldLabel    matlab.ui.control.Label
        InitializePanel              matlab.ui.container.Panel
        CameraLamp                   matlab.ui.control.Lamp
        DAQLamp                      matlab.ui.control.Lamp
        CamerasButton                matlab.ui.control.Button
        NIDAQButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        config
        daq
        src 
        vid
        vid_info
        camera_frame_rate
        camera_names
        figures
        stimulus
    end
    
    methods (Access = private)
        
        function plot_camera(app)
            for iCamera = 1:numel(app.camera_names)
                app.src.(app.camera_names{iCamera}).TriggerMode = 'Off'; 
                fig = figure;
                fig.Name = app.camera_names{iCamera};
                hAxes = axes(fig,'Units','Normalize','Position',[0 0 1 1]);
                hImage = image(hAxes, zeros(...
                    app.vid_info.(app.camera_names{iCamera}).MaxHeight-app.config.cameras.offset_y, ...
                    app.vid_info.(app.camera_names{iCamera}).MaxWidth-app.config.cameras.offset_x, ... 
                    app.vid_info.(app.camera_names{iCamera}).NativeDataType));
                set(gcf,'menubar','none','NumberTitle','off',...
                    'Position',app.config.cameras.figure_positions{iCamera});
                preview(app.vid.(app.camera_names{iCamera}),hImage)
                axis equal
                app.figures.(app.camera_names{iCamera}) = fig;
            end

            % Add alignment
            if app.FemalealignmentCheckBox.Value || app.MalealignmentCheckBox.Value
                if app.FemalealignmentCheckBox.Value
                    alignment.camera_01.leg = [445,170,20,20]; % Middle is 960/2 = 480 px
                    alignment.camera_02.leg = [515,170,20,20];
                elseif app.MalealignmentCheckBox.Value
                    alignment.camera_01.leg = [475,190,20,20]; % Middle is 960/2 = 480 px
                    alignment.camera_02.leg = [485,190,20,20];
                end
                alignment.camera_01.treadmill = [120,340,730,220];
                alignment.camera_02.treadmill = [120,340,730,220];

                for iCamera = 1:numel(app.camera_names)
                    figure(app.figures.(app.camera_names{iCamera}))
                    hold on
                    rectangle('Position', ...
                        [alignment.(app.camera_names{iCamera}).leg(1,1), ...
                        alignment.(app.camera_names{iCamera}).leg(1,2), ...
                        alignment.(app.camera_names{iCamera}).leg(1,3), ...
                        alignment.(app.camera_names{iCamera}).leg(1,4)], ...
                        'EdgeColor','w','LineWidth',2 );
                    rectangle('Position', ...
                        [alignment.(app.camera_names{iCamera}).treadmill(1,1), ...
                        alignment.(app.camera_names{iCamera}).treadmill(1,2), ...
                        alignment.(app.camera_names{iCamera}).treadmill(1,3), ...
                        alignment.(app.camera_names{iCamera}).treadmill(1,4)], ...
                        'EdgeColor','w','LineWidth',2 );  
                    hold off        
                end
            end
        end

        function close_camera(app)
            for iCamera = 1:numel(app.camera_names)
                if isfield(app.figures,app.camera_names{iCamera})
                    if isvalid(app.figures.(app.camera_names{iCamera}))
                        close(app.figures.(app.camera_names{iCamera}))
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load config file 
            path_config = 'config.toml';
            config = toml.read(path_config);
            app.config = toml.map_to_struct(config);
            app.DirEditField.Value = app.config.dir.data_default;

            % Suggest experimental folder name
            c = datetime("today");
            m = sprintf('%02d', month(c));
            d = sprintf('%02d', day(c));
            app.FolderEditField.Value = [num2str(year(c)),'-',m,'-',d,'_A01'];

            % Set initial trial number
            app.TrialnumberEditField.Value = '001';
            % Set initial trial duration
            app.TrialdurationEditField.Value = '7';

            % Reset cameras
            imaqreset
        end

        % Button pushed function: NIDAQButton
        function NIDAQButtonPushed(app, event)
            fprintf('Initializing NIDAQ...\n')
            
            % Create Data Acquisition object 
            app.daq = daq('ni');
            app.daq.Rate = double(app.config.daq.sampling_rate);

            % Turn LED off (clamp it at -10V)
            light_trigger = addoutput(app.daq, app.config.daq.name, 'ao0', 'Voltage'); 
            write(app.daq, -10)

            % Create Data Acquisition object again after write
            app.daq = daq('ni');
            app.daq.Rate = double(app.config.daq.sampling_rate);

            % Set output channels  
            % Note: 'PulseGeneration' is counter output (co), which is sopported on 4
            % channels: ctr0, ctr1, ctr2, ctr3
            camera_trigger = addoutput(app.daq, app.config.daq.name, 'ctr0', 'PulseGeneration'); % ctr0 = PFI12
            camera_trigger.Frequency = double(app.config.cameras.sampling_rate);
            light_trigger = addoutput(app.daq, app.config.daq.name, 'ao0', 'Voltage'); 
           
            % Set input channels 
            addinput(app.daq, app.config.daq.name, 'ai0', 'Voltage'); % camera_01 frames
            addinput(app.daq, app.config.daq.name, 'ai1', 'Voltage'); % camera_02 frames
            addinput(app.daq, app.config.daq.name, 'ai2', 'Voltage'); % Ball x
            addinput(app.daq, app.config.daq.name, 'ai3', 'Voltage'); % Ball y
            addinput(app.daq, app.config.daq.name, 'ai4', 'Voltage'); % Ball z
            addinput(app.daq, app.config.daq.name, 'ai5', 'Voltage'); % Light stimulus

            app.DAQLamp.Color = [0,1,0];
            fprintf('Done\n')
        end

        % Button pushed function: CamerasButton
        function CamerasButtonPushed(app, event)
            fprintf('Initializing cameras...\n')
            
            imaqreset

            % Camera settings
            app.camera_names = app.config.cameras.names;
            camera_ports = app.config.cameras.ports;
            
            % Set camera options 
            % Basler acA1300-200um
            % Pin 1: Line 3, GPIO, trigger in, brown
            % Pin 2: Line 1, opto-coupled I/O input, pink
            % Pin 3: Line 4, GPIO, camera frame out, green
            % Pin 4: Line 2, opto-coupled I/O line, trigger out, yellow
            % Pin 5: Ground for opto-coupled I/O lines, gray
            % Pin 6: Ground for GPIO lines, white/black
            for iCamera = 1:numel(app.camera_names)
                app.vid.(app.camera_names{iCamera}) = videoinput('gentl', camera_ports(iCamera), 'Mono8'); % imaqhwinfo(camera_01_app.vid); 
                triggerconfig(app.vid.(app.camera_names{iCamera}),'hardware'); 
                app.vid.(app.camera_names{iCamera}).LoggingMode = 'memory';
                app.vid.(app.camera_names{iCamera}).FramesPerTrigger = inf; 
            
                app.vid_info.(app.camera_names{iCamera}) = imaqhwinfo(app.vid.(app.camera_names{iCamera}));
                % Record 960 x 560 image. Can't record larger videos with cameras at 300 fps.
                offset_x = double(app.config.cameras.offset_x); 
                offset_y = double(app.config.cameras.offset_y); 
                app.vid.(app.camera_names{iCamera}).ROIPosition = [...
                    offset_x/2, ...
                    offset_y/2, ...
                    app.vid_info.(app.camera_names{iCamera}).MaxWidth-offset_x, ...
                    app.vid_info.(app.camera_names{iCamera}).MaxHeight-offset_y];    
            
                app.src.(app.camera_names{iCamera}) = getselectedsource(app.vid.(app.camera_names{iCamera}));
                app.src.(app.camera_names{iCamera}).LineSelector = 'Line4'; 
                app.src.(app.camera_names{iCamera}).LineMode = 'output';
                app.src.(app.camera_names{iCamera}).LineInverter = 'True'; 
                app.src.(app.camera_names{iCamera}).LineSource = 'ExposureActive';
                app.src.(app.camera_names{iCamera}).LineSelector = 'Line3'; 
                app.src.(app.camera_names{iCamera}).LineMode = 'input';
                
                app.src.(app.camera_names{iCamera}).TriggerSelector = 'FrameStart';
                app.src.(app.camera_names{iCamera}).TriggerMode = 'Off';
                app.src.(app.camera_names{iCamera}).TriggerSource = 'Line3';  
                app.src.(app.camera_names{iCamera}).TriggerActivation = 'RisingEdge';
                app.src.(app.camera_names{iCamera}).ExposureTime = double(app.config.cameras.exposure_time); % mu s              
                app.src.(app.camera_names{iCamera}).Gain = 8.9931973119681032;
                app.src.(app.camera_names{iCamera}).Gamma = 0.600006103515625;
                
                app.src.(app.camera_names{iCamera}).TriggerMode = 'On';
            end
            
            app.CameraLamp.Color = [0,1,0];
            fprintf('Done\n')
        end

        % Value changed function: PreviewcamerasCheckBox
        function PreviewCamerasCheckBox(app, event)
            value = app.PreviewcamerasCheckBox.Value;
            if value 
                plot_camera(app) 
            else
                close_camera(app)
            end
        end

        % Value changed function: FemalealignmentCheckBox
        function FemaleAlignmentCheckBox(app, event)
            value = app.FemalealignmentCheckBox.Value;
            if value
                app.MalealignmentCheckBox.Value = false;
            end
        end

        % Value changed function: MalealignmentCheckBox
        function MaleAlignmentCheckBox(app, event)
            value = app.MalealignmentCheckBox.Value;
            if value
                app.FemalealignmentCheckBox.Value = false;
            end
        end

        % Button pushed function: PreloadButton
        function PreloadButtonPushed(app, event)
            fprintf('Preloading stimulus...\n')
            stimulus_duration = str2double(app.DurationEditField.Value);
            stimulus_onset = str2double(app.OnsetEditField.Value);
            stimulus_intensity = str2double(app.IntensityEditField.Value);
            trial_duration = str2double(app.TrialdurationEditField.Value);

            stimulus_padding = 5000;
            app.stimulus = ones(trial_duration*app.daq.Rate+stimulus_padding,1)*-10;
            if stimulus_duration>0
                if stimulus_onset == 0
                    app.stimulus(app.daq.Rate : stimulus_duration*app.daq.Rate) = stimulus_intensity;
                else
                    app.stimulus(stimulus_onset*app.daq.Rate : (stimulus_onset+stimulus_duration)*app.daq.Rate) = stimulus_intensity;
                end
                app.stimulus(end) = -10;
            end

            flush(app.daq) % Delete previously queued data
            preload(app.daq,app.stimulus)

            app.StimulusLamp.Color = [0,1,0];
            fprintf('Done\n')
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if all(app.DAQLamp.Color == [0,1,0]) ...
                   && all(app.CameraLamp.Color == [0,1,0]) ...
                   && all(app.StimulusLamp.Color == [0,1,0]) ...
                   && exist([app.DirEditField.Value,app.FolderEditField.Value],"dir")==7
             
                trial_name = [app.FolderEditField.Value,'_',app.TrialnumberEditField.Value];

                % Reset cameras
                CamerasButtonPushed(app, event)
                
                % Start cameras
                close_camera(app)
                for iCamera = 1:numel(app.camera_names)
                    start(app.vid.(app.camera_names{iCamera}))
                end

                % Start session
                start(app.daq, 'Continuous')

                fprintf(['Recording ' trial_name,'...\n'])
                
                pause(str2double(app.TrialdurationEditField.Value))

                % Stop session
                stop(app.daq)
                % Stop cameras
                for iCamera = 1:numel(app.camera_names)
                    stop(app.vid.(app.camera_names{iCamera}))
                end
                % Store data
                fprintf('Saving...\n')
                for iCamera = 1:numel(app.camera_names)
                    data.(app.camera_names{iCamera}) = getdata(app.vid.(app.camera_names{iCamera}), app.vid.(app.camera_names{iCamera}).FramesAvailable);
                    
                    writer.(app.camera_names{iCamera}) = VideoWriter([app.DirEditField.Value,app.FolderEditField.Value,'\',trial_name,'_',app.camera_names{iCamera},'.avi'], 'Grayscale AVI');
                    writer.(app.camera_names{iCamera}).FrameRate = app.config.cameras.sampling_rate;

                    open(writer.(app.camera_names{iCamera}))
                    writeVideo(writer.(app.camera_names{iCamera}),data.(app.camera_names{iCamera}))
                    close(writer.(app.camera_names{iCamera}))
                end
                trial_data = read(app.daq,'all');
                               
                save([app.DirEditField.Value,app.FolderEditField.Value,'\',trial_name], 'trial_data')

                % Increase trial number by 1
                app.TrialnumberEditField.Value = sprintf('%03d',str2double(app.TrialnumberEditField.Value)+1);

                fprintf('Done\n')

                % Preload stimulus  
                PreloadButtonPushed(app, event)

                if app.PostviewcamerasCheckBox.Value
                    plot_camera(app)
                end
            else
                fprintf('Check that folder exists in path and all indicators are green\n')
            end
        end

        % Value changed function: TrialdurationEditField
        function TrialdurationChanged(app, event)
            value = app.TrialdurationEditField.Value;
            % Overwrite stimulus only if it has been set 
            if all(app.StimulusLamp.Color == [0,1,0])
                PreloadButtonPushed(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializePanel = uipanel(app.UIFigure);
            app.InitializePanel.Title = 'Initialize ';
            app.NIDAQButton = uibutton(app.InitializePanel, 'push');
            app.NIDAQButton.ButtonPushedFcn = createCallbackFcn(app, @NIDAQButtonPushed, true);
            app.NIDAQButton.Text = 'NI DAQ';
            app.CamerasButton = uibutton(app.InitializePanel, 'push');
            app.CamerasButton.ButtonPushedFcn = createCallbackFcn(app, @CamerasButtonPushed, true);
            app.CamerasButton.Text = 'Cameras';
            app.DAQLamp = uilamp(app.InitializePanel);
            app.CameraLamp = uilamp(app.InitializePanel);
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File';
            app.TrialnumberEditFieldLabel = uilabel(app.FilePanel);
            app.TrialnumberEditFieldLabel.Text = 'Trial number';
            app.TrialnumberEditField = uieditfield(app.FilePanel, 'text');
            app.DirEditFieldLabel = uilabel(app.FilePanel);
            app.DirEditFieldLabel.Text = 'Dir';
            app.DirEditField = uieditfield(app.FilePanel, 'text');
            app.FolderEditFieldLabel = uilabel(app.FilePanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.FilePanel, 'text');
            app.TrialdurationEditFieldLabel = uilabel(app.FilePanel);
            app.TrialdurationEditFieldLabel.Text = 'Trial duration';
            app.TrialdurationEditField = uieditfield(app.FilePanel, 'text');
            app.TrialdurationEditField.ValueChangedFcn = createCallbackFcn(app, @TrialdurationChanged, true);
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.PreviewcamerasCheckBox = uicheckbox(app.ViewPanel);
            app.PreviewcamerasCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewCamerasCheckBox, true);
            app.PreviewcamerasCheckBox.Text = 'Preview cameras';
            app.FemalealignmentCheckBox = uicheckbox(app.ViewPanel);
            app.FemalealignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @FemaleAlignmentCheckBox, true);
            app.FemalealignmentCheckBox.Text = 'Female alignment';
            app.PostviewcamerasCheckBox = uicheckbox(app.ViewPanel);
            app.PostviewcamerasCheckBox.Text = 'Postview cameras';
            app.PostviewcamerasCheckBox.Value = true;
            app.MalealignmentCheckBox = uicheckbox(app.ViewPanel);
            app.MalealignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaleAlignmentCheckBox, true);
            app.MalealignmentCheckBox.Text = 'Male alignment';
            app.StimulusPanel = uipanel(app.UIFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.DurationEditFieldLabel = uilabel(app.StimulusPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.StimulusPanel, 'text');
            app.DurationEditField.Value = '0';
            app.OnsetEditFieldLabel = uilabel(app.StimulusPanel);
            app.OnsetEditFieldLabel.Text = 'Onset';
            app.OnsetEditField = uieditfield(app.StimulusPanel, 'text');
            app.OnsetEditField.Value = '0';
            app.IntensityEditFieldLabel = uilabel(app.StimulusPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.StimulusPanel, 'text');
            app.IntensityEditField.Value = '0';
            app.PreloadButton = uibutton(app.StimulusPanel, 'push');
            app.PreloadButton.ButtonPushedFcn = createCallbackFcn(app, @PreloadButtonPushed, true);
            app.PreloadButton.Text = 'Preload';
            app.StimulusLamp = uilamp(app.StimulusPanel);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fly_on_the_ball_recording_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end